classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % a
        a                      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                  matlab.ui.control.Label            % b
        b                      matlab.ui.control.NumericEditField % [-Inf Inf]
        alpha                  matlab.ui.control.NumericEditField % [-Inf Inf]
        beta                   matlab.ui.control.NumericEditField % [-Inf Inf]
        gamma                  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label5                 matlab.ui.control.Label            % Bords  
        Label6                 matlab.ui.control.Label            % Equation
        Label7                 matlab.ui.control.Label            % u''+
        Label8                 matlab.ui.control.Label            % u+
        Label9                 matlab.ui.control.Label            % u'  =
        f                      matlab.ui.control.EditField       
        LabelNumericEditField2 matlab.ui.control.Label            % h
        h                      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label10                matlab.ui.control.Label            % Step
        Label11                matlab.ui.control.Label            % Exact S...
        ue                     matlab.ui.control.EditField       
        UIAxes                 matlab.ui.control.UIAxes           % Solution
        Button                 matlab.ui.control.Button           % Start
        UIAxes2                matlab.ui.control.UIAxes           % Error
        LabelNumericEditField3 matlab.ui.control.Label            % NUMBER ...
        nods                   matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonPushed(app)
          
            [U,ex,err,n,X]=Main(app.f.Value,app.ue.Value,app.alpha.Value,app.beta.Value,app.gamma.Value,app.a.Value,app.b.Value,app.h.Value);
            plot(app.UIAxes,X,ex,'-b');hold(app.UIAxes);plot(app.UIAxes,X,U,'-r');
            plot(app.UIAxes2,X,err,'r-o');
            app.nods.Value=n;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'numeric');
            app.a.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'b';
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.b.ValueDisplayFormat = '%.3f';
            app.alpha = uieditfield(app.UIFigure, 'numeric');
            app.beta = uieditfield(app.UIFigure, 'numeric');
            app.gamma = uieditfield(app.UIFigure, 'numeric');
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Bords  ';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Equation';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'u''''+';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'u+';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'u''  =';
            app.f = uieditfield(app.UIFigure, 'text');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'h';
            app.h = uieditfield(app.UIFigure, 'numeric');
            app.h.ValueDisplayFormat = '%.3f';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Step';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Exact Solution';
            app.ue = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Solution');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed);
            app.Button.Text = 'Start';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Error');
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'NUMBER OF NODES';
            app.nods = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end